classdef electrolytic_capacitor_v3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        MaterialDropDownLabel          matlab.ui.control.Label
        MaterialDropDown               matlab.ui.control.DropDown
        RadiusEditFieldLabel           matlab.ui.control.Label
        RadiusEditField                matlab.ui.control.NumericEditField
        WidthEditFieldLabel            matlab.ui.control.Label
        WidthEditField                 matlab.ui.control.NumericEditField
        TankLabel                      matlab.ui.control.Label
        CapacitorLabel                 matlab.ui.control.Label
        DistanceEditFieldLabel         matlab.ui.control.Label
        DistanceEditField              matlab.ui.control.NumericEditField
        HeightEditFieldLabel           matlab.ui.control.Label
        HeightEditField                matlab.ui.control.NumericEditField
        mLabel_4                       matlab.ui.control.Label
        mLabel                         matlab.ui.control.Label
        mLabel_2                       matlab.ui.control.Label
        mLabel_3                       matlab.ui.control.Label
        RelativepermittivityEditFieldLabel  matlab.ui.control.Label
        RelativepermittivityEditField  matlab.ui.control.NumericEditField
        CapacitanceReadingKnobLabel    matlab.ui.control.Label
        CapacitanceReadingKnob         matlab.ui.control.Knob
        RightPanel                     matlab.ui.container.Panel
        VolumeEditFieldLabel           matlab.ui.control.Label
        VolumeEditField                matlab.ui.control.NumericEditField
        LitresLabel                    matlab.ui.control.Label
        ejes                           matlab.ui.control.UIAxes
        carga                          matlab.ui.control.UIAxes
        ChargeEditFieldLabel           matlab.ui.control.Label
        ChargeEditField                matlab.ui.control.NumericEditField
        ResistanceEditFieldLabel       matlab.ui.control.Label
        ResistanceEditField            matlab.ui.control.NumericEditField
        InductanceEditFieldLabel       matlab.ui.control.Label
        InductanceEditField            matlab.ui.control.NumericEditField
        qtGaugeLabel                   matlab.ui.control.Label
        qtGauge                        matlab.ui.control.SemicircularGauge
        JALARButton                    matlab.ui.control.Button
        tmaxEditFieldLabel             matlab.ui.control.Label
        tmaxEditField                  matlab.ui.control.NumericEditField
        LiquidLevelEditFieldLabel      matlab.ui.control.Label
        LiquidLevelEditField           matlab.ui.control.NumericEditField
        mLabel_5                       matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = public)
        function captureData(app)
            if (app.WidthEditField.Value ~= 0 && app.DistanceEditField.Value ~= 0 && app.HeightEditField.Value ~= 0 && app.RadiusEditField ~= 0)
            
                width = app.WidthEditField.Value;
                distance = app.DistanceEditField.Value;
                
                height = app.HeightEditField.Value;
                
                radius = app.RadiusEditField.Value;


                 dielectric = material(app, app.MaterialDropDown.Value);     
                     
                 capacitance_min = (width * height * 8.85e-12)/distance;
                 capacitance_max = capacitance_min * (1 + dielectric);


                app.CapacitanceReadingKnob.Limits = [capacitance_min, capacitance_max];
            end
        end
        
        function BoxPlot3C(app,xo, yo, zo, Lx, Ly, Lz, w)
                % w = 0, wire frame; w = 1, rectangles are colored
                Nombre_ejes = app.ejes;
                x = [xo xo xo xo xo+Lx xo+Lx xo+Lx xo+Lx];
                y = [yo yo yo+Ly yo+Ly yo yo yo+Ly yo+Ly];
                z = [zo zo+Lz zo+Lz zo zo zo+Lz zo+Lz zo ];
                index = zeros(6,5);
                index(1,:) = [1 2 3 4 1];
                index(2,:) = [5 6 7 8 5];
                index(3,:) = [1 2 6 5 1];
                index(4,:) = [4 3 7 8 4];
                index(5,:) = [2 6 7 3 2];
                index(6,:) = [1 5 8 4 1];
                c = [0.8 0.8 0.8];
                gris = [0.7 0.7 0.7];
                azul=[0 0.5 0.8];
                
                switch app.MaterialDropDown.Value
                    case "Water"
                        color = azul;
                    case "Alcohol"
                        color = [0.85 0.9 0.9];
                    case "Gasoline"
                        color = [0.95 0.65 0.35];
                    case "Acetone"
                        color = [0.97 0.9 0.969];
                    case "Glycerol"
                        color = [0.97 1 0.71];
                    case "Diesel"
                        color = [0.7 0.89 0.14];
                    case "Other"
                        color = [1 0 0.63];
                end


            for k = 1:6
                if w==1
                fill3(Nombre_ejes,x(index(k,:)), y(index(k,:)), z(index(k,:)), gris)
                
                elseif w==2
                    fill3(Nombre_ejes,x(index(k,:)), y(index(k,:)), z(index(k,:)), color,'FaceAlpha',1,'LineStyle','none')
                    
                elseif w==3
                    fill3(Nombre_ejes,x(index(k,:)), y(index(k,:)), z(index(k,:)), c ,'FaceAlpha',0.6,'LineStyle','none')
                else
                    plot3(Nombre_ejes,x(index(k,:)), y(index(k,:)), z(index(k,:)))
                end
                    hold (Nombre_ejes,'on');
            end
        end
        
        
        function dielectric = material(app, material_input)
            switch material_input
                case "Water"
                    app.RelativepermittivityEditField.Visible = false;
                    app.RelativepermittivityEditFieldLabel.Visible = false;
                    dielectric = 80.4;
                case "Alcohol"
                    app.RelativepermittivityEditField.Visible = false;
                    app.RelativepermittivityEditFieldLabel.Visible = false;
                    dielectric = 24.3;
                case "Gasoline"
                    app.RelativepermittivityEditField.Visible = false;
                    app.RelativepermittivityEditFieldLabel.Visible = false;
                    dielectric = 2;
                case "Acetone"
                    app.RelativepermittivityEditField.Visible = false;
                    app.RelativepermittivityEditFieldLabel.Visible = false;
                    dielectric = 20.7;
                case "Glycerol"
                    app.RelativepermittivityEditField.Visible = false;
                    app.RelativepermittivityEditFieldLabel.Visible = false;
                    dielectric = 42.5;
                case "Diesel"
                    app.RelativepermittivityEditField.Visible = false;
                    app.RelativepermittivityEditFieldLabel.Visible = false;
                    dielectric = 2.1;
                case "Other"
                    app.RelativepermittivityEditField.Visible = true;
                    app.RelativepermittivityEditFieldLabel.Visible = true;
                    dielectric = app.RelativepermittivityEditField.Value;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function JALARButtonPushed(app, event)
            
        end

        % Value changed function: CapacitanceReadingKnob
        function CapacitanceReadingKnobValueChanged(app, event)
             if (app.WidthEditField.Value ~= 0 && app.DistanceEditField.Value ~= 0 && app.HeightEditField.Value ~= 0 && app.RadiusEditField ~= 0)
            
                 width = app.WidthEditField.Value;
                distance = app.DistanceEditField.Value;
                
                height = app.HeightEditField.Value;
                
                radius = app.RadiusEditField.Value;


                 dielectric = material(app, app.MaterialDropDown.Value);     


                 x_comp = distance/2;
                 y_comp = width/2;
                 
                 if x_comp >= radius 
                     distance = (radius*2) - radius/1000;
                     x_comp = distance/2;
                     y_comp = sqrt(radius^2 - x_comp^2);
                     width = y_comp * 2;
                    
                     app.DistanceEditField.Value = distance;
                     app.WidthEditField.Value = width;
                 elseif y_comp >= radius
                     width = (radius * 2) - radius/1000;
                     y_comp = width/2;
                     x_comp = sqrt(radius^2 - y_comp^2);
                     distance = x_comp * 2;
                     app.DistanceEditField.Value = distance;
                     app.WidthEditField.Value = width;
                 else
                     x_comp = distance/2;
                     y_comp = width / 2;
                     y_comp_max = sqrt(radius^2 - x_comp^2);
                     if y_comp > y_comp_max 
                         y_comp = y_comp_max;
                         width = y_comp * 2;
                         app.WidthEditField.Value = width;
                     end    
                 end
                 
                 capacitance_min = (width * height * 8.85e-12)/distance;
                 capacitance_max = capacitance_min * (1 + dielectric);


                 app.CapacitanceReadingKnob.Limits = [capacitance_min, capacitance_max];
                 
                 capacitance = app.CapacitanceReadingKnob.Value;
               
                 laltura = ((capacitance - capacitance_min) * height) / (capacitance_min * dielectric);
                 volume_m3 = pi * (app.RadiusEditField.Value)^2 * laltura;
                 volume_litres = volume_m3 * 1000;
                 if volume_litres <0
                     laltura = 0;
                     volume_litres = 0;
                 end
                 app.VolumeEditField.Value = volume_litres;
                 app.LiquidLevelEditField.Value = laltura;


                 r = radius; %Radio del recipiente
                 h = height ; %Altura del vaso               
                 Nombre_ejes = app.ejes; 
        
                 m_distance= distance/2;
                 BoxPlot3C(app,(m_distance-distance*0.1),(-width/2),0,r*0.01,width,h,1);%placa
                 BoxPlot3C(app,-m_distance,(-width/2),0,r*0.01,width,h,1); %placa
                    
                    
                 xlabel(Nombre_ejes,'x');
                 ylabel(Nombre_ejes,'y');
                 zlabel(Nombre_ejes,'z');
                 title(Nombre_ejes,'Tank');
                
                 [i,j,Z] = cylinder(r);
                 h2=laltura;  %Altura del agua 
                 z2=Z*h2;
                 Z = Z*height;
                 kfrasco=Z;
                 k2=z2;
                 X=i;
                 Y=j;
                 
                
                 switch app.MaterialDropDown.Value
                    case "Water"
                        color = [0 0.5 0.8];
                    case "Alcohol"
                        color = [0.85 0.9 0.9];
                    case "Gasoline"
                        color = [0.95 0.65 0.35];
                    case "Acetone"
                        color = [0.97 0.9 0.969];
                    case "Glycerol"
                        color = [0.97 1 0.71];
                    case "Diesel"
                        color = [0.7 0.89 0.14];
                    case "Other"
                        color = [1 0 0.63];
                end


                 surf(Nombre_ejes,X, Y, kfrasco,'FaceColor',[0.5 0.5 1],'FaceAlpha',0.4,"EdgeColor","none")
                 fill3(Nombre_ejes,X(2,:),Y(2,:),k2(2,:), color);
                 surf(Nombre_ejes,X, Y, k2,'FaceColor',color,'LineStyle','none');
                 hold(Nombre_ejes,"off");
             end
        end

        % Value changed function: DistanceEditField
        function DistanceEditFieldValueChanged(app, event)
            captureData(app)
        end

        % Value changed function: HeightEditField
        function HeightEditFieldValueChanged(app, event)
            captureData(app)
        end

        % Value changed function: WidthEditField
        function WidthEditFieldValueChanged(app, event)
            captureData(app)
        end

        % Value changed function: MaterialDropDown
        function MaterialDropDownValueChanged(app, event)
            useless = material(app, app.MaterialDropDown.Value);
            captureData(app)
        end

        % Value changed function: RelativepermittivityEditField
        function RelativepermittivityEditFieldValueChanged(app, event)
            captureData(app)
        end

        % Button pushed function: JALARButton
        function JALARButtonPushed2(app, event)
            cla(app.carga)
            app.qtGauge.Value = 0;
            if (app.ChargeEditField.Value ~= 0 && app.ResistanceEditField.Value ~= 0 && app.InductanceEditField.Value ~= 0 && app.tmaxEditField.Value ~= 0)
                C = app.CapacitanceReadingKnob.Value;
                R = app.ResistanceEditField.Value;
                L = app.ResistanceEditField.Value;
                q = app.ChargeEditField.Value;
                
                app.qtGauge.Limits = [-q, q];
                
                f = @(x) (q * exp(1)^((-R * x)/(2 * L))) * cos(sqrt((1/(L * C)) - (R/(2 * L))^2)*x);
                iter = 240;
                a = 0;
                b = app.tmaxEditField.Value;
                x_vector = linspace(a,b,iter);
                y_vector = arrayfun(f, x_vector);
                
                xlim(app.carga,[a,b])
                ylim(app.carga,[-q, q])
                
                hold(app.carga)
                for i= 1:iter
                    
                    plot(app.carga, x_vector(i), y_vector(i),"k.")
                    app.qtGauge.Value = y_vector(i);
                    pause(1/2880)  
                end
                hold(app.carga, "off")
            end


        end

        % Callback function
        function DESJALARButtonPushed(app, event)
            hold(app.carga, "off") 
          
            cla(app.carga)
            app.qtGauge.Value = 0;
            pause
        end

        % Callback function
        function ExitButtonPushed(app, event)
            close(app.UIFigure)
            close()
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.MaterialDropDownLabel = uilabel(app.LeftPanel);
            app.MaterialDropDownLabel.Text = 'Material';
            app.MaterialDropDown = uidropdown(app.LeftPanel);
            app.MaterialDropDown.Items = {'Water', 'Alcohol', 'Gasoline', 'Acetone', 'Glycerol', 'Diesel', 'Other', '', '', ''};
            app.MaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @MaterialDropDownValueChanged, true);
            app.MaterialDropDown.Value = 'Water';
            app.RadiusEditFieldLabel = uilabel(app.LeftPanel);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RadiusEditField.Limits = [0 Inf];
            app.WidthEditFieldLabel = uilabel(app.LeftPanel);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.LeftPanel, 'numeric');
            app.WidthEditField.Limits = [0 Inf];
            app.WidthEditField.ValueChangedFcn = createCallbackFcn(app, @WidthEditFieldValueChanged, true);
            app.TankLabel = uilabel(app.LeftPanel);
            app.TankLabel.Text = 'Tank';
            app.CapacitorLabel = uilabel(app.LeftPanel);
            app.CapacitorLabel.Text = 'Capacitor';
            app.DistanceEditFieldLabel = uilabel(app.LeftPanel);
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.DistanceEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DistanceEditField.Limits = [0 Inf];
            app.DistanceEditField.ValueChangedFcn = createCallbackFcn(app, @DistanceEditFieldValueChanged, true);
            app.HeightEditFieldLabel = uilabel(app.LeftPanel);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.LeftPanel, 'numeric');
            app.HeightEditField.Limits = [0 Inf];
            app.HeightEditField.ValueChangedFcn = createCallbackFcn(app, @HeightEditFieldValueChanged, true);
            app.mLabel_4 = uilabel(app.LeftPanel);
            app.mLabel_4.Text = 'm';
            app.mLabel = uilabel(app.LeftPanel);
            app.mLabel.Text = 'm';
            app.mLabel_2 = uilabel(app.LeftPanel);
            app.mLabel_2.Text = 'm';
            app.mLabel_3 = uilabel(app.LeftPanel);
            app.mLabel_3.Text = 'm';
            app.RelativepermittivityEditFieldLabel = uilabel(app.LeftPanel);
            app.RelativepermittivityEditFieldLabel.Visible = 'off';
            app.RelativepermittivityEditFieldLabel.Text = 'Relative permittivity';
            app.RelativepermittivityEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RelativepermittivityEditField.Limits = [1 Inf];
            app.RelativepermittivityEditField.ValueChangedFcn = createCallbackFcn(app, @RelativepermittivityEditFieldValueChanged, true);
            app.RelativepermittivityEditField.Visible = 'off';
            app.RelativepermittivityEditField.Value = 1;
            app.CapacitanceReadingKnobLabel = uilabel(app.LeftPanel);
            app.CapacitanceReadingKnobLabel.Text = 'Capacitance Reading';
            app.CapacitanceReadingKnob = uiknob(app.LeftPanel, 'continuous');
            app.CapacitanceReadingKnob.ValueChangedFcn = createCallbackFcn(app, @CapacitanceReadingKnobValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.VolumeEditFieldLabel = uilabel(app.RightPanel);
            app.VolumeEditFieldLabel.Text = 'Volume';
            app.VolumeEditField = uieditfield(app.RightPanel, 'numeric');
            app.VolumeEditField.Editable = 'off';
            app.LitresLabel = uilabel(app.RightPanel);
            app.LitresLabel.Text = 'Litres';
            app.ejes = uiaxes(app.RightPanel);
            title(app.ejes, 'Volume')
            xlabel(app.ejes, 'X')
            ylabel(app.ejes, 'Y')
            app.carga = uiaxes(app.RightPanel);
            title(app.carga, 'Charge')
            xlabel(app.carga, 'time(s)')
            ylabel(app.carga, 'q (C)')
            app.ChargeEditFieldLabel = uilabel(app.RightPanel);
            app.ChargeEditFieldLabel.Text = 'Charge';
            app.ChargeEditField = uieditfield(app.RightPanel, 'numeric');
            app.ChargeEditField.Limits = [0 Inf];
            app.ResistanceEditFieldLabel = uilabel(app.RightPanel);
            app.ResistanceEditFieldLabel.Text = 'Resistance';
            app.ResistanceEditField = uieditfield(app.RightPanel, 'numeric');
            app.ResistanceEditField.Limits = [0 Inf];
            app.InductanceEditFieldLabel = uilabel(app.RightPanel);
            app.InductanceEditFieldLabel.Text = 'Inductance';
            app.InductanceEditField = uieditfield(app.RightPanel, 'numeric');
            app.InductanceEditField.Limits = [0 Inf];
            app.qtGaugeLabel = uilabel(app.RightPanel);
            app.qtGaugeLabel.Text = 'q(t)';
            app.qtGauge = uigauge(app.RightPanel, 'semicircular');
            app.JALARButton = uibutton(app.RightPanel, 'push');
            app.JALARButton.ButtonPushedFcn = createCallbackFcn(app, @JALARButtonPushed2, true);
            app.JALARButton.Text = 'JALAR';
            app.tmaxEditFieldLabel = uilabel(app.RightPanel);
            app.tmaxEditFieldLabel.Text = 't max';
            app.tmaxEditField = uieditfield(app.RightPanel, 'numeric');
            app.tmaxEditField.Limits = [0 Inf];
            app.LiquidLevelEditFieldLabel = uilabel(app.RightPanel);
            app.LiquidLevelEditFieldLabel.Text = {'Liquid Level'; ''};
            app.LiquidLevelEditField = uieditfield(app.RightPanel, 'numeric');
            app.LiquidLevelEditField.Editable = 'off';
            app.mLabel_5 = uilabel(app.RightPanel);
            app.mLabel_5.Text = 'm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = electrolytic_capacitor_v3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end